classdef Exam1MATLAB_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Response                       matlab.ui.control.TextArea
        FlashButton                    matlab.ui.control.Button
        LEDOffButton                   matlab.ui.control.Button
        LEDOnButton                    matlab.ui.control.Button
        PeriodmsEditField              matlab.ui.control.EditField
        PeriodmsEditFieldLabel         matlab.ui.control.Label
        NumberofFlashesEditField       matlab.ui.control.EditField
        NumberofFlashesEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Sim = ArduinoFrontend
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Response.Value = "";
        end

        % Button pushed function: LEDOnButton
        function LEDOnButtonPushed(app, event)
            command = "ON";
            
            response = app.Sim.sendLEDCommand(command);
            app.Response.Value = response;
        end

        % Button pushed function: LEDOffButton
        function LEDOffButtonPushed(app, event)
            command = "OFF"; 
            
            response = app.Sim.sendLEDCommand(command);
            app.Response.Value = response;  
        end

        % Button pushed function: FlashButton
        function FlashButtonPushed(app, event)
            num = str2double(app.NumberofFlashesEditField.Value);
            period = str2double(app.PeriodmsEditField.Value);

            response = app.Sim.sendFLASHCommand(num, period);
            app.Response.Value = response;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NumberofFlashesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFlashesEditFieldLabel.Text = 'Number of Flashes';
            app.NumberofFlashesEditField = uieditfield(app.UIFigure, 'text');
            app.PeriodmsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodmsEditFieldLabel.Text = 'Period (ms)';
            app.PeriodmsEditField = uieditfield(app.UIFigure, 'text');
            app.LEDOnButton = uibutton(app.UIFigure, 'push');
            app.LEDOnButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOnButtonPushed, true);
            app.LEDOnButton.Text = 'LED On';
            app.LEDOffButton = uibutton(app.UIFigure, 'push');
            app.LEDOffButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOffButtonPushed, true);
            app.LEDOffButton.Text = 'LED Off';
            app.FlashButton = uibutton(app.UIFigure, 'push');
            app.FlashButton.ButtonPushedFcn = createCallbackFcn(app, @FlashButtonPushed, true);
            app.FlashButton.Text = 'Flash';
            app.Response = uitextarea(app.UIFigure);
            app.Response.Value = {'Server Resoponse'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exam1MATLAB_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end