classdef Exam3_Car_MQTT_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab6UIFigure        matlab.ui.Figure
        DistanceLabel       matlab.ui.control.Label
        RightReverseButton  matlab.ui.control.Button
        BothReverseButton   matlab.ui.control.Button
        LeftReverseButton   matlab.ui.control.Button
        RightForwardButton  matlab.ui.control.Button
        BothForwardButton   matlab.ui.control.Button
        LeftForwardButton   matlab.ui.control.Button
        TimeSlider          matlab.ui.control.Slider
        TimeSliderLabel     matlab.ui.control.Label
        RightSlider         matlab.ui.control.Slider
        RightSliderLabel    matlab.ui.control.Label
        LeftSlider          matlab.ui.control.Slider
        LeftSliderLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
        distance
    end
    
    methods (Access = private)

        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message)
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;

            
            % if strcmp(messageType, "left")
            %     messagePayload = messageStruct.payload;
            % end
            % 
            % if strcmp(messageType, "both")
            %     messagePayload = messageStruct.payload;
            % end
            % 
            % if strcmp(messageType, "right")
            %     messagePayload = messageStruct.payload;
            % end

            if strcmp(messageType, "ultra")
                messagePayload = messageStruct.payload;
                app.distance = round(messagePayload);
                app.DistanceLabel.Text = sprintf("Counter = %d cm", app.distance);
            end

        end


        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;

            if nargin > 2
                messageStruct.payload = messagePayload;
            end

            messageJson = jsonencode(messageStruct);
            write(app.mqClient, "me435/mccreebs/to_pi", messageJson);
            fprintf("Sent: %s\n", messageJson);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/mccreebs/#", "Callback", @app.myCallback)
        end

        % Button pushed function: LeftForwardButton
        function LeftForwardButtonPushed(app, event)
            direction = 1;
            time = app.TimeSlider.Value;
            speed = app.LeftSlider.Value;

            app.sendMessage("left", [direction, time, speed])

            fprintf("Side: Left | Direction: %.0f | Time: %.1f | Speed: %.0f\n", direction, time, speed)
        end

        % Button pushed function: BothForwardButton
        function BothForwardButtonPushed(app, event)
            direction = 1;
            time = app.TimeSlider.Value;
            Lspeed = app.LeftSlider.Value;
            Rspeed = app.RightSlider.Value;

            app.sendMessage("both", [direction, time, Lspeed, Rspeed])

            fprintf("Side: Both | Direction: %.0f | Time: %.1f | Speed Left: %.0f | Speed Right: %.0f\n", ...
                direction, time, Lspeed, Rspeed)   
        end

        % Button pushed function: RightForwardButton
        function RightForwardButtonPushed(app, event)
            direction = 1;
            time = app.TimeSlider.Value;
            speed = app.RightSlider.Value;

            app.sendMessage("right", [direction, time, speed])

            fprintf("Side: Right | Direction: %.0f | Time: %.1f | Speed: %.0f\n", direction, time, speed)
        end

        % Button pushed function: LeftReverseButton
        function LeftReverseButtonPushed(app, event)
            direction = 0;
            time = app.TimeSlider.Value;
            speed = app.LeftSlider.Value;

            app.sendMessage("left", [direction, time, speed])

            fprintf("Side: Left | Direction: %.0f | Time: %.1f | Speed: %.0f\n", direction, time, speed)  
        end

        % Button pushed function: BothReverseButton
        function BothReverseButtonPushed(app, event)
            direction = 0;
            time = app.TimeSlider.Value;
            Lspeed = app.LeftSlider.Value;
            Rspeed = app.RightSlider.Value;

            app.sendMessage("both", [direction, time, Lspeed, Rspeed])

            fprintf("Side: Both | Direction: %.0f | Time: %.1f | Speed Left: %.0f | Speed Right: %.0f\n", ...
                direction, time, Lspeed, Rspeed)    
        end

        % Button pushed function: RightReverseButton
        function RightReverseButtonPushed(app, event)
            direction = 0;
            time = app.TimeSlider.Value;
            speed = app.RightSlider.Value;

            app.sendMessage("right", [direction, time, speed])

            fprintf("Side: Right | Direction: %.0f | Time: %.1f | Speed: %.0f\n", direction, time, speed)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab6UIFigure = uifigure('Visible', 'off');
            app.Lab6UIFigure.Name = 'Lab 6';
            app.LeftSliderLabel = uilabel(app.Lab6UIFigure);
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSlider = uislider(app.Lab6UIFigure);
            app.LeftSlider.Value = 40;
            app.RightSliderLabel = uilabel(app.Lab6UIFigure);
            app.RightSliderLabel.Text = 'Right';
            app.RightSlider = uislider(app.Lab6UIFigure);
            app.RightSlider.Value = 40;
            app.TimeSliderLabel = uilabel(app.Lab6UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.Lab6UIFigure);
            app.TimeSlider.Limits = [0 4];
            app.TimeSlider.Value = 1;
            app.LeftForwardButton = uibutton(app.Lab6UIFigure, 'push');
            app.LeftForwardButton.ButtonPushedFcn = createCallbackFcn(app, @LeftForwardButtonPushed, true);
            app.LeftForwardButton.Text = 'Left Forward';
            app.BothForwardButton = uibutton(app.Lab6UIFigure, 'push');
            app.BothForwardButton.ButtonPushedFcn = createCallbackFcn(app, @BothForwardButtonPushed, true);
            app.BothForwardButton.Text = 'Both Forward';
            app.RightForwardButton = uibutton(app.Lab6UIFigure, 'push');
            app.RightForwardButton.ButtonPushedFcn = createCallbackFcn(app, @RightForwardButtonPushed, true);
            app.RightForwardButton.Text = 'Right Forward';
            app.LeftReverseButton = uibutton(app.Lab6UIFigure, 'push');
            app.LeftReverseButton.ButtonPushedFcn = createCallbackFcn(app, @LeftReverseButtonPushed, true);
            app.LeftReverseButton.Text = 'Left Reverse';
            app.BothReverseButton = uibutton(app.Lab6UIFigure, 'push');
            app.BothReverseButton.ButtonPushedFcn = createCallbackFcn(app, @BothReverseButtonPushed, true);
            app.BothReverseButton.Text = 'Both Reverse';
            app.RightReverseButton = uibutton(app.Lab6UIFigure, 'push');
            app.RightReverseButton.ButtonPushedFcn = createCallbackFcn(app, @RightReverseButtonPushed, true);
            app.RightReverseButton.Text = 'Right Reverse';
            app.DistanceLabel = uilabel(app.Lab6UIFigure);
            app.DistanceLabel.Text = 'Distance';
            % Show the figure after all components are created
            app.Lab6UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exam3_Car_MQTT_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab6UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab6UIFigure)
        end
    end
end