classdef ABB_IRB120 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ABBIRB120UIFigure    matlab.ui.Figure
        MotionButtonsPanel   matlab.ui.container.Panel
        GoButton             matlab.ui.control.Button
        HomeButton           matlab.ui.control.Button
        SavedPositionsPanel  matlab.ui.container.Panel
        Go3Button            matlab.ui.control.Button
        Go2Button            matlab.ui.control.Button
        Go1Button            matlab.ui.control.Button
        Save3Button          matlab.ui.control.Button
        Save2Button          matlab.ui.control.Button
        Save1Button          matlab.ui.control.Button
        TextArea_3           matlab.ui.control.TextArea
        TextArea_2           matlab.ui.control.TextArea
        TextArea_1           matlab.ui.control.TextArea
        JointPositionsPanel  matlab.ui.container.Panel
        J2EditField          matlab.ui.control.NumericEditField
        J2EditFieldLabel     matlab.ui.control.Label
        J6EditField          matlab.ui.control.NumericEditField
        J6EditFieldLabel     matlab.ui.control.Label
        J5EditField          matlab.ui.control.NumericEditField
        J5EditFieldLabel     matlab.ui.control.Label
        J4EditField          matlab.ui.control.NumericEditField
        J4EditFieldLabel     matlab.ui.control.Label
        J3EditField          matlab.ui.control.NumericEditField
        J3EditFieldLabel     matlab.ui.control.Label
        J1EditField          matlab.ui.control.NumericEditField
        J1EditFieldLabel     matlab.ui.control.Label
        Theta1Slider         matlab.ui.control.Slider
        Theta1SliderLabel    matlab.ui.control.Label
        Theta2Slider         matlab.ui.control.Slider
        Theta2SliderLabel    matlab.ui.control.Label
        Theta3Slider         matlab.ui.control.Slider
        Theta3SliderLabel    matlab.ui.control.Label
        Theta4Slider         matlab.ui.control.Slider
        Theta4SliderLabel    matlab.ui.control.Label
        Theta5Slider         matlab.ui.control.Slider
        Theta5SliderLabel    matlab.ui.control.Label
        Theta6Slider         matlab.ui.control.Slider
        Theta6SliderLabel    matlab.ui.control.Label
        axes_arm             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        link5Patch
        link5Vertices
        link6Patch
        link6Vertices

        abbThetas = [0, 0, 0, 0, 0, 0]

        savedAngles1 = [0, 0, 0, 0, 0, 0]
        savedAngles2 = [0, 0, 0, 0, 0, 0]
        savedAngles3 = [0, 0, 0, 0, 0, 0]
        savedAngles4 = [0, 0, 0, 0, 0, 0]
        savedAngles5 = [0, 0, 0, 0, 0, 0]
        savedAngles6 = [0, 0, 0, 0, 0, 0]
    end

    methods (Access = private)

        function updateArm(app)
            
            % Fix joint angles
            jointAngles = app.abbThetas;
            jointAngles(2) = jointAngles(2) - 90;

            % Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(0, 290, -90, jointAngles(1));
            A2 = create_A_matrix(270, 0, 0, jointAngles(2));
            A3 = create_A_matrix(70, 0, -90, jointAngles(3));
            A4 = create_A_matrix(0, 302, 90, jointAngles(4));
            A5 = create_A_matrix(0, 0, -90, jointAngles(5));
            A6 = create_A_matrix(0, 72, 0, jointAngles(6));

            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1*A2;
            T0_3 = A1*A2*A3;
            T0_4 = A1*A2*A3*A4;
            T0_5 = A1*A2*A3*A4*A5;
            T0_6 = A1*A2*A3*A4*A5*A6;

            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            link4VerticesWRTground = T0_4 * app.link4Vertices;
            link5VerticesWRTground = T0_5 * app.link5Vertices;
            link6VerticesWRTground = T0_6 * app.link6Vertices;

            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');
            set(app.link5Patch,'Vertices', link5VerticesWRTground(1:3,:)');
            set(app.link6Patch,'Vertices', link6VerticesWRTground(1:3,:)');

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [23 23];
            app.axes_arm.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.axes_arm.XLim = [-200 600];
            app.axes_arm.YLim = [-600 600];
            app.axes_arm.ZLim = [0 800];

            % Create vertices for all the patches
            makeStlLink("ABBLink0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("ABBLink1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("ABBLink2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("ABBLink3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.link4Patch = makeStlLink("ABBLink4.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));

            app.link5Patch = makeStlLink("ABBLink5.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link5Vertices = get(app.link5Patch, 'Vertices')';
            app.link5Vertices(4,:) = ones(1, size(app.link5Vertices,2));

            app.link6Patch = makeStlLink("ABBLink6.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link6Vertices = get(app.link6Patch, 'Vertices')';
            app.link6Vertices(4,:) = ones(1, size(app.link6Vertices,2));

            app.TextArea_1.Value = sprintf("[%7.2f, %7.2f, %7.2f, %7.2f, %7.2f, %7.2f]", app.savedAngles1);
            app.TextArea_2.Value = sprintf("[%7.2f, %7.2f, %7.2f, %7.2f, %7.2f, %7.2f]", app.savedAngles2);
            app.TextArea_3.Value = sprintf("[%7.2f, %7.2f, %7.2f, %7.2f, %7.2f, %7.2f]", app.savedAngles3);

            app.updateArm();
        end

        % Value changing function: Theta6Slider
        function Theta6SliderValueChanging(app, event)
            changingValue = event.Value;
            app.abbThetas(6) = changingValue;

            app.J6EditField.Value = app.abbThetas(6);

            app.updateArm()
        end

        % Value changing function: Theta5Slider
        function Theta5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.abbThetas(5) = changingValue;

            app.J5EditField.Value = app.abbThetas(5);

            app.updateArm()
        end

        % Value changing function: Theta4Slider
        function Theta4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.abbThetas(4) = changingValue;

            app.J4EditField.Value = app.abbThetas(4);

            app.updateArm()
        end

        % Value changing function: Theta3Slider
        function Theta3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.abbThetas(3) = changingValue;

            app.J3EditField.Value = app.abbThetas(3);

            app.updateArm()
        end

        % Value changing function: Theta2Slider
        function Theta2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.abbThetas(2) = changingValue;

            app.J2EditField.Value = app.abbThetas(2);

            app.updateArm()
        end

        % Value changing function: Theta1Slider
        function Theta1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.abbThetas(1) = changingValue;

            app.J1EditField.Value = app.abbThetas(1);

            app.updateArm()
        end

        % Button pushed function: Save1Button
        function Save1ButtonPushed(app, event)
            app.savedAngles1(1) = app.J1EditField.Value;
            app.savedAngles1(2) = app.J2EditField.Value;
            app.savedAngles1(3) = app.J3EditField.Value;
            app.savedAngles1(4) = app.J4EditField.Value;
            app.savedAngles1(5) = app.J5EditField.Value;
            app.savedAngles1(6) = app.J6EditField.Value;
            
            app.TextArea_1.Value = sprintf("[%7.2f, %7.2f, %7.2f, %7.2f, %7.2f, %7.2f]", app.savedAngles1);
        end

        % Button pushed function: Save2Button
        function Save2ButtonPushed(app, event)
            app.savedAngles2(1) = app.J1EditField.Value;
            app.savedAngles2(2) = app.J2EditField.Value;
            app.savedAngles2(3) = app.J3EditField.Value;
            app.savedAngles2(4) = app.J4EditField.Value;
            app.savedAngles2(5) = app.J5EditField.Value;
            app.savedAngles2(6) = app.J6EditField.Value;
            
            app.TextArea_2.Value = sprintf("[%7.2f, %7.2f, %7.2f, %7.2f, %7.2f, %7.2f]", app.savedAngles2);
        end

        % Button pushed function: Save3Button
        function Save3ButtonPushed(app, event)
            app.savedAngles3(1) = app.J1EditField.Value;
            app.savedAngles3(2) = app.J2EditField.Value;
            app.savedAngles3(3) = app.J3EditField.Value;
            app.savedAngles3(4) = app.J4EditField.Value;
            app.savedAngles3(5) = app.J5EditField.Value;
            app.savedAngles3(6) = app.J6EditField.Value;
            
            app.TextArea_3.Value = sprintf("[%7.2f, %7.2f, %7.2f, %7.2f, %7.2f, %7.2f]", app.savedAngles3);
        end

        % Button pushed function: Go1Button
        function Go1ButtonPushed(app, event)
            app.J1EditField.Value = app.savedAngles1(1);
            app.J2EditField.Value = app.savedAngles1(2);
            app.J3EditField.Value = app.savedAngles1(3);
            app.J4EditField.Value = app.savedAngles1(4);
            app.J5EditField.Value = app.savedAngles1(5);
            app.J6EditField.Value = app.savedAngles1(6);
            
            app.abbThetas(1) = app.J1EditField.Value;
            app.abbThetas(2) = app.J2EditField.Value;
            app.abbThetas(3) = app.J3EditField.Value;
            app.abbThetas(4) = app.J4EditField.Value;
            app.abbThetas(5) = app.J5EditField.Value;
            app.abbThetas(6) = app.J6EditField.Value;

            app.Theta1Slider.Value = app.abbThetas(1);
            app.Theta2Slider.Value = app.abbThetas(2);
            app.Theta3Slider.Value = app.abbThetas(3);
            app.Theta4Slider.Value = app.abbThetas(4);
            app.Theta5Slider.Value = app.abbThetas(5);
            app.Theta6Slider.Value = app.abbThetas(6);

            app.updateArm()
        end

        % Button pushed function: Go2Button
        function Go2ButtonPushed(app, event)
            app.J1EditField.Value = app.savedAngles2(1);
            app.J2EditField.Value = app.savedAngles2(2);
            app.J3EditField.Value = app.savedAngles2(3);
            app.J4EditField.Value = app.savedAngles2(4);
            app.J5EditField.Value = app.savedAngles2(5);
            app.J6EditField.Value = app.savedAngles2(6);
            
            app.abbThetas(1) = app.J1EditField.Value;
            app.abbThetas(2) = app.J2EditField.Value;
            app.abbThetas(3) = app.J3EditField.Value;
            app.abbThetas(4) = app.J4EditField.Value;
            app.abbThetas(5) = app.J5EditField.Value;
            app.abbThetas(6) = app.J6EditField.Value;

            app.Theta1Slider.Value = app.abbThetas(1);
            app.Theta2Slider.Value = app.abbThetas(2);
            app.Theta3Slider.Value = app.abbThetas(3);
            app.Theta4Slider.Value = app.abbThetas(4);
            app.Theta5Slider.Value = app.abbThetas(5);
            app.Theta6Slider.Value = app.abbThetas(6);

            app.updateArm()
        end

        % Button pushed function: Go3Button
        function Go3ButtonPushed(app, event)
            app.J1EditField.Value = app.savedAngles3(1);
            app.J2EditField.Value = app.savedAngles3(2);
            app.J3EditField.Value = app.savedAngles3(3);
            app.J4EditField.Value = app.savedAngles3(4);
            app.J5EditField.Value = app.savedAngles3(5);
            app.J6EditField.Value = app.savedAngles3(6);
            
            app.abbThetas(1) = app.J1EditField.Value;
            app.abbThetas(2) = app.J2EditField.Value;
            app.abbThetas(3) = app.J3EditField.Value;
            app.abbThetas(4) = app.J4EditField.Value;
            app.abbThetas(5) = app.J5EditField.Value;
            app.abbThetas(6) = app.J6EditField.Value;

            app.Theta1Slider.Value = app.abbThetas(1);
            app.Theta2Slider.Value = app.abbThetas(2);
            app.Theta3Slider.Value = app.abbThetas(3);
            app.Theta4Slider.Value = app.abbThetas(4);
            app.Theta5Slider.Value = app.abbThetas(5);
            app.Theta6Slider.Value = app.abbThetas(6);

            app.updateArm()
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            homeValue = 0;
            app.abbThetas(1) = homeValue;
            app.abbThetas(2) = homeValue;
            app.abbThetas(3) = homeValue;
            app.abbThetas(4) = homeValue;
            app.abbThetas(5) = homeValue;
            app.abbThetas(6) = homeValue;

            app.Theta1Slider.Value = homeValue;
            app.Theta2Slider.Value = homeValue;
            app.Theta3Slider.Value = homeValue;
            app.Theta4Slider.Value = homeValue;
            app.Theta5Slider.Value = homeValue;
            app.Theta6Slider.Value = homeValue;

            app.J1EditField.Value = app.abbThetas(1);
            app.J2EditField.Value = app.abbThetas(2);
            app.J3EditField.Value = app.abbThetas(3);
            app.J4EditField.Value = app.abbThetas(4);
            app.J5EditField.Value = app.abbThetas(5);
            app.J6EditField.Value = app.abbThetas(6);

            app.updateArm()
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.abbThetas(1) = app.J1EditField.Value;
            app.abbThetas(2) = app.J2EditField.Value;
            app.abbThetas(3) = app.J3EditField.Value;
            app.abbThetas(4) = app.J4EditField.Value;
            app.abbThetas(5) = app.J5EditField.Value;
            app.abbThetas(6) = app.J6EditField.Value;

            app.Theta1Slider.Value = app.abbThetas(1);
            app.Theta2Slider.Value = app.abbThetas(2);
            app.Theta3Slider.Value = app.abbThetas(3);
            app.Theta4Slider.Value = app.abbThetas(4);
            app.Theta5Slider.Value = app.abbThetas(5);
            app.Theta6Slider.Value = app.abbThetas(6);

            app.updateArm()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ABBIRB120UIFigure = uifigure('Visible', 'off');
            app.ABBIRB120UIFigure.Name = 'ABB IRB120';
            app.axes_arm = uiaxes(app.ABBIRB120UIFigure);
            title(app.axes_arm, 'Arm')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.JointPositionsPanel = uipanel(app.ABBIRB120UIFigure);
            app.JointPositionsPanel.Title = 'Joint Positions';
            app.Theta6SliderLabel = uilabel(app.JointPositionsPanel);
            app.Theta6SliderLabel.Text = 'Theta 6';
            app.Theta6Slider = uislider(app.JointPositionsPanel);
            app.Theta6Slider.Limits = [-180 180];
            app.Theta6Slider.ValueChangingFcn = createCallbackFcn(app, @Theta6SliderValueChanging, true);
            app.Theta5SliderLabel = uilabel(app.JointPositionsPanel);
            app.Theta5SliderLabel.Text = 'Theta 5';
            app.Theta5Slider = uislider(app.JointPositionsPanel);
            app.Theta5Slider.Limits = [-90 90];
            app.Theta5Slider.ValueChangingFcn = createCallbackFcn(app, @Theta5SliderValueChanging, true);
            app.Theta4SliderLabel = uilabel(app.JointPositionsPanel);
            app.Theta4SliderLabel.Text = 'Theta 4';
            app.Theta4Slider = uislider(app.JointPositionsPanel);
            app.Theta4Slider.Limits = [-90 90];
            app.Theta4Slider.ValueChangingFcn = createCallbackFcn(app, @Theta4SliderValueChanging, true);
            app.Theta3SliderLabel = uilabel(app.JointPositionsPanel);
            app.Theta3SliderLabel.Text = 'Theta 3';
            app.Theta3Slider = uislider(app.JointPositionsPanel);
            app.Theta3Slider.Limits = [-10 50];
            app.Theta3Slider.ValueChangingFcn = createCallbackFcn(app, @Theta3SliderValueChanging, true);
            app.Theta2SliderLabel = uilabel(app.JointPositionsPanel);
            app.Theta2SliderLabel.Text = 'Theta 2';
            app.Theta2Slider = uislider(app.JointPositionsPanel);
            app.Theta2Slider.Limits = [-10 40];
            app.Theta2Slider.ValueChangingFcn = createCallbackFcn(app, @Theta2SliderValueChanging, true);
            app.Theta1SliderLabel = uilabel(app.JointPositionsPanel);
            app.Theta1SliderLabel.Text = 'Theta 1';
            app.Theta1Slider = uislider(app.JointPositionsPanel);
            app.Theta1Slider.Limits = [-90 90];
            app.Theta1Slider.ValueChangingFcn = createCallbackFcn(app, @Theta1SliderValueChanging, true);
            app.J1EditFieldLabel = uilabel(app.JointPositionsPanel);
            app.J1EditFieldLabel.Text = 'J1';
            app.J1EditField = uieditfield(app.JointPositionsPanel, 'numeric');
            app.J3EditFieldLabel = uilabel(app.JointPositionsPanel);
            app.J3EditFieldLabel.Text = 'J3';
            app.J3EditField = uieditfield(app.JointPositionsPanel, 'numeric');
            app.J4EditFieldLabel = uilabel(app.JointPositionsPanel);
            app.J4EditFieldLabel.Text = 'J4';
            app.J4EditField = uieditfield(app.JointPositionsPanel, 'numeric');
            app.J5EditFieldLabel = uilabel(app.JointPositionsPanel);
            app.J5EditFieldLabel.Text = 'J5';
            app.J5EditField = uieditfield(app.JointPositionsPanel, 'numeric');
            app.J6EditFieldLabel = uilabel(app.JointPositionsPanel);
            app.J6EditFieldLabel.Text = 'J6';
            app.J6EditField = uieditfield(app.JointPositionsPanel, 'numeric');
            app.J2EditFieldLabel = uilabel(app.JointPositionsPanel);
            app.J2EditFieldLabel.Text = 'J2';
            app.J2EditField = uieditfield(app.JointPositionsPanel, 'numeric');
            app.SavedPositionsPanel = uipanel(app.ABBIRB120UIFigure);
            app.SavedPositionsPanel.Title = 'Saved Positions';
            app.TextArea_1 = uitextarea(app.SavedPositionsPanel);
            app.TextArea_2 = uitextarea(app.SavedPositionsPanel);
            app.TextArea_3 = uitextarea(app.SavedPositionsPanel);
            app.Save1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Save1Button.ButtonPushedFcn = createCallbackFcn(app, @Save1ButtonPushed, true);
            app.Save1Button.Text = 'Save 1';
            app.Save2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Save2Button.ButtonPushedFcn = createCallbackFcn(app, @Save2ButtonPushed, true);
            app.Save2Button.Text = 'Save 2';
            app.Save3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Save3Button.ButtonPushedFcn = createCallbackFcn(app, @Save3ButtonPushed, true);
            app.Save3Button.Text = 'Save 3';
            app.Go1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Go1Button.ButtonPushedFcn = createCallbackFcn(app, @Go1ButtonPushed, true);
            app.Go1Button.Text = 'Go 1';
            app.Go2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Go2Button.ButtonPushedFcn = createCallbackFcn(app, @Go2ButtonPushed, true);
            app.Go2Button.Text = 'Go 2';
            app.Go3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Go3Button.ButtonPushedFcn = createCallbackFcn(app, @Go3ButtonPushed, true);
            app.Go3Button.Text = 'Go 3';
            app.MotionButtonsPanel = uipanel(app.ABBIRB120UIFigure);
            app.MotionButtonsPanel.Title = 'Motion Buttons';
            app.HomeButton = uibutton(app.MotionButtonsPanel, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.GoButton = uibutton(app.MotionButtonsPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            % Show the figure after all components are created
            app.ABBIRB120UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ABB_IRB120

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ABBIRB120UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ABBIRB120UIFigure)
        end
    end
end