classdef AdeeptArm_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmGUIUIFigure   matlab.ui.Figure
        SaveButton_3           matlab.ui.control.Button
        SaveButton_2           matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        LoadButton_3           matlab.ui.control.Button
        LoadButton_2           matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        SavedLabel3            matlab.ui.control.Label
        SavedLabel2            matlab.ui.control.Label
        SavedLabel             matlab.ui.control.Label
        HomeButton             matlab.ui.control.Button
        GoButton               matlab.ui.control.Button
        J3ValueEditField       matlab.ui.control.NumericEditField
        J3ValueEditFieldLabel  matlab.ui.control.Label
        J2ValueEditField       matlab.ui.control.NumericEditField
        J2ValueEditFieldLabel  matlab.ui.control.Label
        J1ValueEditField       matlab.ui.control.NumericEditField
        J1ValueEditFieldLabel  matlab.ui.control.Label
        Joint3Slider           matlab.ui.control.Slider
        Joint3SliderLabel      matlab.ui.control.Label
        Joint2Slider           matlab.ui.control.Slider
        Joint2SliderLabel      matlab.ui.control.Label
        Joint1Slider           matlab.ui.control.Slider
        Joint1SliderLabel      matlab.ui.control.Label
        axes_arm               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        SavedPos1 = [0 0 0]
        SavedPos2 = [0 0 0]
        SavedPos3 = [0 0 0]
        jointAngles = [0,0,0]
    end
    
    methods (Access = private)
        
        function updateArm(app)
            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(64, 0, 0, app.jointAngles(1));
            A2 = create_A_matrix(0, 0, -90, app.jointAngles(2));
            A3 = create_A_matrix(0, 140, 0, app.jointAngles(3));

            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1*A2;
            T0_3 = A1*A2*A3;

            % TODO: Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;

            % TODO: Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.axes_arm.XLim = [-160 250];
            app.axes_arm.YLim = [-250 250];
            app.axes_arm.ZLim = [-22 22];

            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.updateArm()

        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            % TODO: Also update numeric value text field
            app.J1ValueEditField.Value = app.jointAngles(1);

            app.updateArm()
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.jointAngles(1) = app.J1ValueEditField.Value;
            app.jointAngles(2) = app.J2ValueEditField.Value;
            app.jointAngles(3) = app.J3ValueEditField.Value;
            
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm()
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.jointAngles(1) = 0;
            app.jointAngles(2) = 0;
            app.jointAngles(3) = 0;

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm() 
        end

        % Value changed function: J2ValueEditField
        function J2ValueEditFieldValueChanged(app, event)

        end

        % Callback function
        function J3ValueEditFieldValueChanged(app, event)

        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            % TODO: Also update numeric value text field
            app.J2ValueEditField.Value = app.jointAngles(2);

            app.updateArm()            
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            % TODO: Also update numeric value text field
            app.J3ValueEditField.Value = app.jointAngles(3);

            app.updateArm()            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.jointAngles(1) = app.SavedPos1(1);
            app.jointAngles(2) = app.SavedPos1(2);
            app.jointAngles(3) = app.SavedPos1(3);

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm()
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SavedPos1(1) = app.J1ValueEditField.Value;
            app.SavedPos1(2) = app.J2ValueEditField.Value;
            app.SavedPos1(3) = app.J3ValueEditField.Value;
 
            app.SavedLabel.Text = sprintf("[%.0f %.0f %.0f]", app.SavedPos1(1), app.SavedPos1(2), app.SavedPos1(3));
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            app.jointAngles(1) = app.SavedPos2(1);
            app.jointAngles(2) = app.SavedPos2(2);
            app.jointAngles(3) = app.SavedPos2(3);

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm()
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            app.SavedPos2(1) = app.J1ValueEditField.Value;
            app.SavedPos2(2) = app.J2ValueEditField.Value;
            app.SavedPos2(3) = app.J3ValueEditField.Value;

            app.SavedLabel2.Text = sprintf("[%.0f %.0f %.0f]", app.SavedPos2(1), app.SavedPos2(2), app.SavedPos2(3));
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            app.jointAngles(1) = app.SavedPos3(1);
            app.jointAngles(2) = app.SavedPos3(2);
            app.jointAngles(3) = app.SavedPos3(3);

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm()
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            app.SavedPos3(1) = app.J1ValueEditField.Value;
            app.SavedPos3(2) = app.J2ValueEditField.Value;
            app.SavedPos3(3) = app.J3ValueEditField.Value;

            app.SavedLabel3.Text = sprintf("[%.0f %.0f %.0f]", app.SavedPos3(1), app.SavedPos3(2), app.SavedPos3(3));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmGUIUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmGUIUIFigure.Name = 'Adeept Arm GUI';
            app.axes_arm = uiaxes(app.AdeeptArmGUIUIFigure);
            title(app.axes_arm, 'Adeept Arm')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.Joint1SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.J1ValueEditFieldLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.J1ValueEditFieldLabel.Text = 'J1 Value';
            app.J1ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.J2ValueEditFieldLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.J2ValueEditFieldLabel.Text = 'J2 Value';
            app.J2ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.J2ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J2ValueEditFieldValueChanged, true);
            app.J3ValueEditFieldLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.J3ValueEditFieldLabel.Text = 'J3 Value';
            app.J3ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.GoButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.HomeButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.SavedLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.SavedLabel.Text = '[0 0 0]';
            app.SavedLabel2 = uilabel(app.AdeeptArmGUIUIFigure);
            app.SavedLabel2.Text = '[0 0 0]';
            app.SavedLabel3 = uilabel(app.AdeeptArmGUIUIFigure);
            app.SavedLabel3.Text = '[0 0 0]';
            app.LoadButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LoadButton_2 = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.LoadButton_3 = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.SaveButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveButton_2 = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SaveButton_3 = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Text = 'Save';
            % Show the figure after all components are created
            app.AdeeptArmGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdeeptArm_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmGUIUIFigure)
        end
    end
end