classdef PlateLoaderGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ExtendButton     matlab.ui.control.Button
        RetractButton    matlab.ui.control.Button
        Button_5         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Button_1         matlab.ui.control.Button
        ImageClaw        matlab.ui.control.Image
        ImageBackground  matlab.ui.control.Image
    end

    
    properties (Access = private)
        ClawPos
        ExtendPos
        ClawImages
        ClawIndex = 3;
    end


    methods (Access = private)
        
        function moveClaw(app, ClawIndex)
            app.ImageClaw.Position = app.ClawPos(ClawIndex, :);
            app.ImageClaw.ImageSource = app.ClawImages(1);
        end
        
        function extendClaw(app, ClawIndex)
            app.ImageClaw.Position = app.ExtendPos(ClawIndex, :);
            app.ImageClaw.ImageSource = app.ClawImages(1);
        end

        function retractClaw(app, ClawIndex)
            app.ImageClaw.Position = app.ClawPos(ClawIndex, :);
            app.ImageClaw.ImageSource = app.ClawImages(1);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            fprintf("Hello\n");

            app.ClawImages = ["gripper_closed_no_plate.jpg", "gripper_open_no_plate.jpg", "gripper_with_plate.jpg"];

            app.ClawPos(1, :) = [201,409,100,100];
            app.ClawPos(2, :) = [294,409,100,100];
            app.ClawPos(3, :) = [390,409,100,100];
            app.ClawPos(4, :) = [481,409,100,100];
            app.ClawPos(5, :) = [577,409,100,100];

            app.ExtendPos(1, :) = [201,281,100,100];
            app.ExtendPos(2, :) = [294,281,100,100];
            app.ExtendPos(3, :) = [390,281,100,100];
            app.ExtendPos(4, :) = [481,281,100,100];
            app.ExtendPos(5, :) = [577,281,100,100];
app.ClawPos

        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.ClawIndex = 1;
            app.moveClaw(app.ClawIndex)

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.ClawIndex = 2;
            app.moveClaw(app.ClawIndex)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.ClawIndex = 3;
            app.moveClaw(app.ClawIndex)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.ClawIndex = 4;
            app.moveClaw(app.ClawIndex)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.ClawIndex = 5;
            app.moveClaw(app.ClawIndex)
        end

        % Button pushed function: ExtendButton
        function ExtendButtonPushed(app, event)
                app.extendClaw(app.ClawIndex)
        end

        % Button pushed function: RetractButton
        function RetractButtonPushed(app, event)
                app.retractClaw(app.ClawIndex)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageBackground = uiimage(app.UIFigure);
            app.ImageBackground.ImageSource = 'robot_background.jpg';
            app.ImageClaw = uiimage(app.UIFigure);
            app.ImageClaw.ImageSource = fullfile(pathToMLAPP, 'gripper_open_no_plate.jpg');
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.RetractButton = uibutton(app.UIFigure, 'push');
            app.RetractButton.ButtonPushedFcn = createCallbackFcn(app, @RetractButtonPushed, true);
            app.RetractButton.Text = 'Retract';
            app.ExtendButton = uibutton(app.UIFigure, 'push');
            app.ExtendButton.ButtonPushedFcn = createCallbackFcn(app, @ExtendButtonPushed, true);
            app.ExtendButton.Text = 'Extend';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlateLoaderGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end