classdef PlateLoaderGUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlateLoaderGUIUIFigure  matlab.ui.Figure
        Plate2                  matlab.ui.control.Image
        Plate                   matlab.ui.control.Image
        SpecialMovesPanel       matlab.ui.container.Panel
        ThankYouButton          matlab.ui.control.Button
        ChokeSlamButton         matlab.ui.control.Button
        MovePlatePanel          matlab.ui.container.Panel
        ToDropDown              matlab.ui.control.DropDown
        ToLabel                 matlab.ui.control.Label
        FromDropDown            matlab.ui.control.DropDown
        FromDropDownLabel       matlab.ui.control.Label
        MovePlateButton         matlab.ui.control.Button
        SetTimeDelaysPanel      matlab.ui.container.Panel
        ResponsesPanel          matlab.ui.container.Panel
        Response                matlab.ui.control.TextArea
        GetStatusButton         matlab.ui.control.Button
        ConnectionPanel         matlab.ui.container.Panel
        PortConnect             matlab.ui.control.NumericEditField
        DisconnectButton        matlab.ui.control.Button
        ConnectButton           matlab.ui.control.Button
        ZAxisPanel              matlab.ui.container.Panel
        RetractButton           matlab.ui.control.Button
        ExtendButton            matlab.ui.control.Button
        GripperPanel            matlab.ui.container.Panel
        OpenButton              matlab.ui.control.Button
        CloseButton             matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        ExtendBars              matlab.ui.control.Image
        Button_5                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button_1                matlab.ui.control.Button
        ImageClaw               matlab.ui.control.Image
        ImageBackground         matlab.ui.control.Image
    end


    properties (Access = private)
        ClawPos
        ExtendPos
        BarPos
        PlateGroundPos
        PlatePickedPos
        ClawImages
        OtherImages
        ClawIndex = 3;
        ClawGrip = 2;
        isHoldingPlate = false;
        isConnected = false;
        Robot
        Sim = PlateLoaderSim
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            app.Response.Value = "";
            app.ExtendBars.Visible = 'off';
            app.Plate.Visible = 'on';
            app.Plate2.Visible = 'on';

            app.ClawImages = ["gripper_open_no_plate.jpg", ...
                "gripper_closed_no_plate.jpg", "gripper_with_plate.jpg"];
            app.OtherImages = ["extended_bars.jpg", "plate_only.jpg"];

            % Retracted Claw Position:
            app.ClawPos(1, :) = [202,461,100,100];
            app.ClawPos(2, :) = [296,461,100,100];
            app.ClawPos(3, :) = [392,461,100,100];
            app.ClawPos(4, :) = [486,461,100,100];
            app.ClawPos(5, :) = [581,461,100,100];

            % Extended Claw Position:
            app.ExtendPos(1, :) = [202,335,100,100];
            app.ExtendPos(2, :) = [296,335,100,100];
            app.ExtendPos(3, :) = [392,335,100,100];
            app.ExtendPos(4, :) = [486,335,100,100];
            app.ExtendPos(5, :) = [581,335,100,100];

            % Extension Bar Position:
            app.BarPos(1, :) = [196.5,412,110,128];
            app.BarPos(2, :) = [291,412,110,128];
            app.BarPos(3, :) = [387,412,110,128];
            app.BarPos(4, :) = [481,412,110,128];
            app.BarPos(5, :) = [576,412,110,128];

            % Plate Ground Position:
            app.PlateGroundPos(1, :) = [212,318,79,87];
            app.PlateGroundPos(2, :) = [306,318,79,87];
            app.PlateGroundPos(3, :) = [402,318,79,87];
            app.PlateGroundPos(4, :) = [496,318,79,87];
            app.PlateGroundPos(5, :) = [591,318,79,87];
            app.PlateGroundPos(6, :) = [591,330,79,87];

            % Plate Picked up Position:
            app.PlatePickedPos(1, :) = [212,448,79,87];
            app.PlatePickedPos(2, :) = [306,448,79,87];
            app.PlatePickedPos(3, :) = [402,448,79,87];
            app.PlatePickedPos(4, :) = [496,448,79,87];
            app.PlatePickedPos(5, :) = [591,448,79,87];

        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.ClawIndex = 1;
            app.ExtendBars.Visible = 'off';

            if app.isConnected
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
                app.Sim.x(app.ClawIndex);
                app.Robot.x(app.ClawIndex);
            else
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
                app.Sim.x(app.ClawIndex);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.ClawIndex = 2;
            app.ExtendBars.Visible = 'off';

            if app.isConnected
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
                app.Sim.x(app.ClawIndex);
                app.Robot.x(app.ClawIndex);
            else
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
                app.Sim.x(app.ClawIndex);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.ClawIndex = 3;
            app.ExtendBars.Visible = 'off';

            if app.isConnected
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
                app.Sim.x(app.ClawIndex);
                app.Robot.x(app.ClawIndex);
            else
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
                app.Sim.x(app.ClawIndex);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.ClawIndex = 4;
            app.ExtendBars.Visible = 'off';

            if app.isConnected
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
                app.Sim.x(app.ClawIndex);
                app.Robot.x(app.ClawIndex);
            else
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
                app.Sim.x(app.ClawIndex);
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.ClawIndex = 5;
            app.ExtendBars.Visible = 'off';

            if app.isConnected
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
                app.Sim.x(app.ClawIndex);
                app.Robot.x(app.ClawIndex);
            else
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
                app.Sim.x(app.ClawIndex);
            end
        end

        % Button pushed function: ExtendButton
        function ExtendButtonPushed(app, event)
            if app.isConnected
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ExtendPos(app.ClawIndex, :);
                
                app.ExtendBars.ImageSource = app.OtherImages(1);
                app.ExtendBars.Position = app.BarPos(app.ClawIndex, :);
                app.ExtendBars.Visible = 'on';

                app.Sim.extend();
                app.Robot.extend();
            else
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ExtendPos(app.ClawIndex, :);

                app.ExtendBars.ImageSource = app.OtherImages(1);
                app.ExtendBars.Position = app.BarPos(app.ClawIndex, :);
                app.ExtendBars.Visible = 'on';

                app.Sim.extend();
            end
        end

        % Button pushed function: RetractButton
        function RetractButtonPushed(app, event)
            if app.isConnected
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);

                app.ExtendBars.Visible = 'off';

                app.Sim.retract();
                app.Robot.retract();
            else
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
                app.ExtendBars.Visible = 'off';
                app.Sim.retract();
            end
        end

        % Button pushed function: GetStatusButton
        function GetStatusButtonPushed(app, event)
            app.Response.Value = "";

            if app.isConnected
                [xPos, zAxis, grip, plate] = app.Robot.getProperties();
            else
                [xPos, zAxis, grip, plate] = app.Sim.getProperties();
            end

            % Z-Axis Status:
            if zAxis == 1
                zAxis = sprintf("EXTENDED");
            else
                zAxis = sprintf("RETRACTED");
            end

            % Gripper Status:
            if grip == 1
                grip = sprintf("CLOSED");
            else
                grip = sprintf("OPEN");
            end

            % Plate Status:
            if plate == 1
                plate = sprintf("PRESENT");
            else
                plate = sprintf("NONE");
            end

            app.Response.Value = [
                sprintf("X-Position: %d, Z-Axis: %s,", xPos, zAxis);
                sprintf("Gripper: %s, Plate: %s", grip, plate)];
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.ClawGrip = 1;
            
            if app.isConnected
                app.Response.Value = "";
                app.Robot.open();
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            else
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.ClawGrip = 2;
            
            if app.isConnected
                app.Response.Value = "";
                app.Robot.close();
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            else
                app.Response.Value = "";
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            end
        end

        % Button pushed function: MovePlateButton
        function MovePlateButtonPushed(app, event)
            app.Response.Value = "";
            app.Plate2.Visible = 'off';
            from = str2double(app.FromDropDown.Value);
            to = str2double(app.ToDropDown.Value);
            if from == to
                app.Response.Value = sprintf("ERROR: NO DISPLACEMENT");
            else
                if app.isConnected
                    app.Plate.ImageSource = app.OtherImages(2);
                    app.Plate.Position = app.PlateGroundPos(to, :);
                    app.Plate.Visible = 'on';
                    app.Sim.movePlate(from, to);
                    app.Robot.movePlate(from, to);
                    pause(6);
                    app.Plate.Visible = 'off';
                else
                    app.Plate.ImageSource = app.OtherImages(2);
                    app.Plate.Position = app.PlateGroundPos(to, :);
                    app.Plate.Visible = 'on';
                    app.Sim.movePlate(from, to);
                    pause(6)
                    app.Plate1.Visible = 'off';

                end


                app.Response.Value = sprintf("PLATE MOVED FROM" + ...
                    " POSITION %d TO %d",from,to);
            end

        end

        % Button pushed function: ChokeSlamButton
        function ChokeSlamButtonPushed(app, event)
            app.Response.Value = "";
            app.Plate.Position = app.PlateGroundPos(1, :);
            app.Plate2.Position = app.PlateGroundPos(3, :);
            app.Plate.Visible = 'on';
            app.Plate2.Visible = 'on';
            pause(0.75)
            app.retractClaw(app.ClawIndex)
            pause(0.75)
            app.ImageClaw.ImageSource = app.ClawImages(1);
            pause(0.75)
            app.moveClaw(1)
            pause(0.75)
            app.ImageClaw.ImageSource = app.ClawImages(2);
            pause(0.75)
            app.extendClaw(1)
            pause(0.75)
            app.ImageClaw.ImageSource = app.ClawImages(3);
            app.Plate.Visible = 'off';
            pause(0.75)
            app.retractClaw(1)
            pause(0.75)
            app.moveClaw(5)
            pause(0.75)
            app.extendClaw(5)
            pause(0.75)
            app.Plate.Position = app.PlateGroundPos(5, :);
            app.Plate.Visible = 'on';
            app.ImageClaw.ImageSource = app.ClawImages(2);
            pause(0.75)
            app.retractClaw(5)
            pause(0.75)
            app.ImageClaw.ImageSource = app.ClawImages(1);
            pause(0.75)

            app.moveClaw(3)
            pause(0.75)
            app.ImageClaw.ImageSource = app.ClawImages(2);
            pause(0.75)
            app.extendClaw(3)
            pause(0.75)
            app.ImageClaw.ImageSource = app.ClawImages(3);
            app.Plate2.Visible = 'off';
            pause(0.75)
            app.retractClaw(3)
            pause(0.75)
            app.moveClaw(5)
            pause(0.75)
            app.ImageClaw.ImageSource = app.ClawImages(2);
            app.Plate2.Position = app.PlateGroundPos(6, :);
            app.Plate2.Visible = 'on';  
            pause(0.75)
            app.moveClaw(3)
            pause(0.75)
            app.ImageClaw.ImageSource = app.ClawImages(1);
        end

        % Button pushed function: ThankYouButton
        function ThankYouButtonPushed(app, event)
            app.Response.Value = "";
            app.Plate.Visible = 'off';
            app.Plate2.Visible = 'off';
            pause(0.75)
            app.retractClaw(app.ClawIndex)
            pause(0.75)
            app.ImageClaw.ImageSource = app.ClawImages(1);
            pause(0.75)
            app.moveClaw(3)
            pause(0.75)
            app.ImageClaw.ImageSource = app.ClawImages(2);
            for i = 1:3
                pause(0.5)
                app.extendClaw(3)
                app.Response.Value = "THANK YOU!";
                pause(0.5)
                app.retractClaw(3)
                app.Response.Value = "";
            end
            pause(0.75)
            app.ImageClaw.ImageSource = app.ClawImages(1);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Grab the number from the numeric edit field
            portNum = app.PortConnect.Value; 

            try
                % Instantiate the Robot and assign it to the app property
                app.Robot = PlateLoader(portNum);
                app.isConnected = true;
                app.Response.Value = sprintf("Connected to port %d", portNum);
            catch
                app.Response.Value = sprintf("ERROR: Could not connect to robot on port %d.", portNum);
                app.isConnected = false;
            end
        end

        % Value changed function: PortConnect
        function PortConnectValueChanged(app, event)

        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if app.isConnected
                app.Robot = []; % Deletes the object and frees the port
                app.isConnected = false;
                app.Response.Value = "Robot disconnected.";
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ClawGrip = 2;
            app.ClawIndex = 3;
            
            if app.isConnected
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
                app.ExtendBars.Visible = 'off';

                app.Sim.reset();
                app.Robot.reset();
            else
                app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
                app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
                app.ExtendBars.Visible = 'off';
                
                app.Sim.reset();
            end
        end

        % Callback function: not associated with a component
        function UITableDisplayDataChanged(app, event)

            
        end

        % Callback function: not associated with a component
        function UITableCellEdit(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PlateLoaderGUIUIFigure = uifigure('Visible', 'off');
            app.PlateLoaderGUIUIFigure.Name = 'Plate Loader GUI';
            app.ImageBackground = uiimage(app.PlateLoaderGUIUIFigure);
            app.ImageBackground.ImageSource = fullfile(pathToMLAPP, 'robot_background.jpg');
            app.ImageClaw = uiimage(app.PlateLoaderGUIUIFigure);
            app.ImageClaw.ImageSource = fullfile(pathToMLAPP, 'gripper_open_no_plate.jpg');
            app.Button_1 = uibutton(app.PlateLoaderGUIUIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.PlateLoaderGUIUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.PlateLoaderGUIUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.PlateLoaderGUIUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.PlateLoaderGUIUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.ExtendBars = uiimage(app.PlateLoaderGUIUIFigure);
            app.ExtendBars.ImageSource = fullfile(pathToMLAPP, 'extended_bars.jpg');
            app.ResetButton = uibutton(app.PlateLoaderGUIUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GripperPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.GripperPanel.Title = 'Gripper';
            app.CloseButton = uibutton(app.GripperPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.GripperPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ZAxisPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.ZAxisPanel.Title = 'Z-Axis';
            app.ExtendButton = uibutton(app.ZAxisPanel, 'push');
            app.ExtendButton.ButtonPushedFcn = createCallbackFcn(app, @ExtendButtonPushed, true);
            app.ExtendButton.Text = 'Extend';
            app.RetractButton = uibutton(app.ZAxisPanel, 'push');
            app.RetractButton.ButtonPushedFcn = createCallbackFcn(app, @RetractButtonPushed, true);
            app.RetractButton.Text = 'Retract';
            app.ConnectionPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.PortConnect = uieditfield(app.ConnectionPanel, 'numeric');
            app.PortConnect.Limits = [0 999];
            app.PortConnect.ValueChangedFcn = createCallbackFcn(app, @PortConnectValueChanged, true);
            app.ResponsesPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.ResponsesPanel.Title = 'Responses';
            app.GetStatusButton = uibutton(app.ResponsesPanel, 'push');
            app.GetStatusButton.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButtonPushed, true);
            app.GetStatusButton.Text = 'Get Status';
            app.Response = uitextarea(app.ResponsesPanel);
            app.SetTimeDelaysPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.SetTimeDelaysPanel.Title = 'Set Time Delays';
            app.MovePlatePanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.MovePlatePanel.Title = 'Move Plate';
            app.MovePlateButton = uibutton(app.MovePlatePanel, 'push');
            app.MovePlateButton.ButtonPushedFcn = createCallbackFcn(app, @MovePlateButtonPushed, true);
            app.MovePlateButton.Text = 'Move Plate';
            app.FromDropDownLabel = uilabel(app.MovePlatePanel);
            app.FromDropDownLabel.Text = 'From:';
            app.FromDropDown = uidropdown(app.MovePlatePanel);
            app.FromDropDown.Items = {'1', '2', '3', '4', '5'};
            app.FromDropDown.Value = '1';
            app.ToLabel = uilabel(app.MovePlatePanel);
            app.ToLabel.Text = 'To:';
            app.ToDropDown = uidropdown(app.MovePlatePanel);
            app.ToDropDown.Items = {'1', '2', '3', '4', '5'};
            app.ToDropDown.Value = '1';
            app.SpecialMovesPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.SpecialMovesPanel.Title = 'Special Moves';
            app.ChokeSlamButton = uibutton(app.SpecialMovesPanel, 'push');
            app.ChokeSlamButton.ButtonPushedFcn = createCallbackFcn(app, @ChokeSlamButtonPushed, true);
            app.ChokeSlamButton.Text = 'Choke Slam';
            app.ThankYouButton = uibutton(app.SpecialMovesPanel, 'push');
            app.ThankYouButton.ButtonPushedFcn = createCallbackFcn(app, @ThankYouButtonPushed, true);
            app.ThankYouButton.Text = 'Thank You!';
            app.Plate = uiimage(app.PlateLoaderGUIUIFigure);
            app.Plate.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            app.Plate2 = uiimage(app.PlateLoaderGUIUIFigure);
            app.Plate2.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            % Show the figure after all components are created
            app.PlateLoaderGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlateLoaderGUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlateLoaderGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlateLoaderGUIUIFigure)
        end
    end
end