classdef PlateLoaderGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlateLoaderGUIUIFigure  matlab.ui.Figure
        Plate2                  matlab.ui.control.Image
        Plate                   matlab.ui.control.Image
        SpecialMovesPanel       matlab.ui.container.Panel
        ThankYouButton          matlab.ui.control.Button
        ChokeSlamButton         matlab.ui.control.Button
        MovePlatePanel          matlab.ui.container.Panel
        ToDropDown              matlab.ui.control.DropDown
        ToLabel                 matlab.ui.control.Label
        FromDropDown            matlab.ui.control.DropDown
        FromDropDownLabel       matlab.ui.control.Label
        MovePlateButton         matlab.ui.control.Button
        SetTimeDelaysPanel      matlab.ui.container.Panel
        ResetDelaysButton       matlab.ui.control.Button
        SetDelaysButton         matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        ResponsesPanel          matlab.ui.container.Panel
        Response                matlab.ui.control.TextArea
        GetStatusButton         matlab.ui.control.Button
        ZAxisPanel              matlab.ui.container.Panel
        RetractButton           matlab.ui.control.Button
        ExtendButton            matlab.ui.control.Button
        GripperPanel            matlab.ui.container.Panel
        OpenButton              matlab.ui.control.Button
        CloseButton             matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        ExtendBars              matlab.ui.control.Image
        Button_5                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button_1                matlab.ui.control.Button
        ImageClaw               matlab.ui.control.Image
        ImageBackground         matlab.ui.control.Image
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
        ClawPos
        ExtendPos
        BarPos
        PlateGroundPos
        PlatePickedPos
        ClawImages
        OtherImages
        ClawIndex = 3;
        ClawGrip = 2;
        isHoldingPlate = false;
        isConnected = false;
        Robot
        Sim = PlateLoaderFrontend
        defaultTimeTable
        updatedUItable
        PlateRest
        AudioPlayer
    end
    
    methods (Access = private)
        % Moving:
        function moveClaw(app, ClawIndex)
            app.ImageClaw.Position = app.ClawPos(ClawIndex, :);
            app.ExtendBars.Visible = 'off';
        end

        % Extension:
        function extendClaw(app, ClawIndex)
            app.ImageClaw.Position = app.ExtendPos(ClawIndex, :);
            app.ExtendBars.ImageSource = app.OtherImages(1);
            app.ExtendBars.Position = app.BarPos(ClawIndex, :);
            app.ExtendBars.Visible = 'on';
        end

        % Retraction:
        function retractClaw(app, ClawIndex)
            app.ImageClaw.Position = app.ClawPos(ClawIndex, :);
            app.ExtendBars.Visible = 'off';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            app.Response.Value = "";
            app.ExtendBars.Visible = 'off';
            app.Plate.Visible = 'on';
            app.Plate2.Visible = 'on';

            app.ClawImages = ["gripper_open_no_plate.jpg", ...
                "gripper_closed_no_plate.jpg", "gripper_with_plate.jpg"];
            app.OtherImages = ["extended_bars.jpg", "plate_only.jpg"];

            % Retracted Claw Position:
            app.ClawPos(1, :) = [202,461,100,100];
            app.ClawPos(2, :) = [296,461,100,100];
            app.ClawPos(3, :) = [392,461,100,100];
            app.ClawPos(4, :) = [486,461,100,100];
            app.ClawPos(5, :) = [581,461,100,100];

            % Extended Claw Position:
            app.ExtendPos(1, :) = [202,335,100,100];
            app.ExtendPos(2, :) = [296,335,100,100];
            app.ExtendPos(3, :) = [392,335,100,100];
            app.ExtendPos(4, :) = [486,335,100,100];
            app.ExtendPos(5, :) = [581,335,100,100];

            % Extension Bar Position:
            app.BarPos(1, :) = [196.5,412,110,128];
            app.BarPos(2, :) = [291,412,110,128];
            app.BarPos(3, :) = [387,412,110,128];
            app.BarPos(4, :) = [481,412,110,128];
            app.BarPos(5, :) = [576,412,110,128];

            % Plate Ground Position:
            app.PlateGroundPos(1, :) = [212,318,79,87];
            app.PlateGroundPos(2, :) = [306,318,79,87];
            app.PlateGroundPos(3, :) = [402,318,79,87];
            app.PlateGroundPos(4, :) = [496,318,79,87];
            app.PlateGroundPos(5, :) = [591,318,79,87];
            app.PlateGroundPos(6, :) = [591,330,79,87];

            % Plate Picked up Position:
            app.PlatePickedPos(1, :) = [212,448,79,87];
            app.PlatePickedPos(2, :) = [306,448,79,87];
            app.PlatePickedPos(3, :) = [402,448,79,87];
            app.PlatePickedPos(4, :) = [496,448,79,87];
            app.PlatePickedPos(5, :) = [591,448,79,87];

            % Plate Rest Position:
            app.PlateRest(1, :) = [314,271,79,87];
            app.PlateRest(2, :) = [491,271,79,87];

            % Time Table
            app.defaultTimeTable = [0 60 20 30 0
                                0 0 30 30 0
                                0 30 0 30 0
                                0 30 30 0 0
                                0 30 20 60 0];
            dTimeTab = app.defaultTimeTable(:, 2:4);

            app.UITable.RowName = {'1'; '2'; '3'; '4'; '5'};
            app.UITable.ColumnWidth = {47.5, 47.5, 47.5};
            app.UITable.ColumnEditable = true;
            
            app.UITable.Data = dTimeTab;
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.ClawIndex = 1;
            app.ExtendBars.Visible = 'off';
            app.Response.Value = "";
            pause(0.25)
            app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
            command = 'X-AXIS 1';

            response = app.Sim.sendCommand(command);
            app.Response.Value = response;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.ClawIndex = 2;
            app.ExtendBars.Visible = 'off';
            app.Response.Value = "";
            pause(0.25)
            app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
            command = 'X-AXIS 2';

            response = app.Sim.sendCommand(command);
            app.Response.Value = response;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.ClawIndex = 3;
            app.ExtendBars.Visible = 'off';
            app.Response.Value = "";
            pause(0.25)
            app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
            command = 'X-AXIS 3';

            response = app.Sim.sendCommand(command);
            app.Response.Value = response;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.ClawIndex = 4;
            app.ExtendBars.Visible = 'off';
            app.Response.Value = "";
            pause(0.25)
            app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
            command = 'X-AXIS 4';

            response = app.Sim.sendCommand(command);
            app.Response.Value = response;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.ClawIndex = 5;
            app.ExtendBars.Visible = 'off';
            app.Response.Value = "";
            pause(0.25)
            app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
            command = 'X-AXIS 5';

            response = app.Sim.sendCommand(command);
            app.Response.Value = response;
        end

        % Button pushed function: ExtendButton
        function ExtendButtonPushed(app, event)
            app.Response.Value = "";
            pause(0.25)

            app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            app.ImageClaw.Position = app.ExtendPos(app.ClawIndex, :);

            app.ExtendBars.ImageSource = app.OtherImages(1);
            app.ExtendBars.Position = app.BarPos(app.ClawIndex, :);
            app.ExtendBars.Visible = 'on';

            command = 'Z-AXIS EXTEND';

            response = app.Sim.sendCommand(command);
            app.Response.Value = response;
        end

        % Button pushed function: RetractButton
        function RetractButtonPushed(app, event)
            app.Response.Value = "";
            pause(0.25)
            app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);

            app.ExtendBars.Visible = 'off';
            command = 'Z-AXIS RETRACT';

            response = app.Sim.sendCommand(command);
            app.Response.Value = response;
        end

        % Button pushed function: GetStatusButton
        function GetStatusButtonPushed(app, event)
            app.Response.Value = "";
            pause(0.25)

            command = 'LOADER_STATUS';

            response = app.Sim.sendCommand(command);
            app.Response.Value = response;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.ClawGrip = 1;
            app.Response.Value = "";
            pause(0.25)
            app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            command = 'GRIPPER OPEN';

            response = app.Sim.sendCommand(command);
            app.Response.Value = response;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.ClawGrip = 2;
            app.Response.Value = "";
            pause(0.25)
            app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            command = 'GRIPPER CLOSE';

            response = app.Sim.sendCommand(command);
            app.Response.Value = response;
        end

        % Button pushed function: MovePlateButton
        function MovePlateButtonPushed(app, event)
            app.Response.Value = "";
            pause(0.25)
            app.Plate2.Position = app.PlateRest(2, :);
            from = str2double(app.FromDropDown.Value);
            to = str2double(app.ToDropDown.Value);

            app.Plate.ImageSource = app.OtherImages(2);
            app.Plate.Position = app.PlateGroundPos(to, :);
            app.Plate.Visible = 'on';
            % Sim
            app.Plate.ImageSource = app.OtherImages(2);
            app.Plate.Position = app.PlateGroundPos(from, :);
            app.Plate.Visible = 'on';
            pause(0.25)
            app.retractClaw(app.ClawIndex)
            pause(0.25)
            app.ImageClaw.ImageSource = app.ClawImages(1);
            pause(0.25)
            app.moveClaw(from)
            pause(0.25)
            app.extendClaw(from)
            pause(0.25)
            app.ImageClaw.ImageSource = app.ClawImages(3);
            app.Plate.Visible = 'off';
            pause(0.25)
            app.retractClaw(from)
            pause(0.25)
            app.moveClaw(to)
            pause(0.25)
            app.extendClaw(to)
            pause(0.25)
            app.Plate.Position = app.PlateGroundPos(to, :);
            app.Plate.Visible = 'on';
            app.ImageClaw.ImageSource = app.ClawImages(1);
            pause(0.25)
            app.retractClaw(to)
            pause(0.25)
            app.moveClaw(3)

            command = 'MOVE ' + string(from) + ' ' + string(to);

            response = app.Sim.sendCommand(command);
            app.Response.Value = response;
            
            pause(1)
            app.Plate.Position = app.PlateRest(1, :);
        end

        % Button pushed function: ChokeSlamButton
        function ChokeSlamButtonPushed(app, event)
            app.Response.Value = "";
            app.Plate.Position = app.PlateGroundPos(1, :);
            app.Plate2.Position = app.PlateGroundPos(3, :);
            app.Plate.Visible = 'on';
            app.Plate2.Visible = 'on';
            pause(0.25)
            app.retractClaw(app.ClawIndex)
            pause(0.25)
            app.moveClaw(1)
            pause(0.25)
            app.ImageClaw.ImageSource = app.ClawImages(1);
            pause(0.25)
            app.extendClaw(1)
            pause(0.25)
            app.ImageClaw.ImageSource = app.ClawImages(3);
            app.Plate.Visible = 'off';
            pause(0.25)
            app.retractClaw(1)
            [y, Fs] = audioread('WatchOutWatchOut.wav');
            sound(y,Fs)
            pause(0.25)
            app.moveClaw(5)
            pause(0.25)
            app.extendClaw(5)
            pause(0.25)
            app.Plate.Position = app.PlateGroundPos(5, :);
            app.Plate.Visible = 'on';
            app.ImageClaw.ImageSource = app.ClawImages(1);
            pause(0.25)
            app.retractClaw(5)
            pause(0.25)
            app.moveClaw(3)
            pause(0.25)
            app.extendClaw(3)
            pause(0.25)
            app.ImageClaw.ImageSource = app.ClawImages(3);
            app.Plate2.Visible = 'off';
            % app.Plate2.Position = app.PlateRest(2, :);
            pause(0.25)
            app.retractClaw(3)
            pause(0.25)
            app.moveClaw(5)
            pause(2.5)
            app.ImageClaw.ImageSource = app.ClawImages(1);
            app.ClawGrip = 1;
            app.Plate2.Position = app.PlateGroundPos(6, :);
            app.Plate2.Visible = 'on';
            pause(0.25)
            app.moveClaw(3)
            
            if app.isConnected
                app.Robot.chokeSlam();
            end

            pause(5)
            % app.Plate.Visible = 'off';
            app.Plate.Position = app.PlateRest(1, :);
            % app.Plate2.Visible = 'off';
            app.Plate2.Position = app.PlateRest(2, :);
        end

        % Button pushed function: ThankYouButton
        function ThankYouButtonPushed(app, event)
            app.Response.Value = "";
            pause(0.25)
            % app.Plate.Visible = 'off';
            % app.Plate2.Visible = 'off';
            app.Plate.Position = app.PlateRest(1, :);
            app.Plate2.Position = app.PlateRest(2, :);

            pause(0.25)
            app.retractClaw(app.ClawIndex)
            pause(0.25)
            app.moveClaw(3)
            pause(0.25)
            app.ClawGrip = 1;
            app.ImageClaw.ImageSource = app.ClawImages(1);
            for i = 1:3
                pause(0.5)
                app.extendClaw(3)
                app.Response.Value = "THANK YOU!";
                pause(0.5)
                app.retractClaw(3)
                app.Response.Value = "";
            end
            
            if app.isConnected
                app.Robot.thankYou()
            end
        end

        % Callback function: not associated with a component
        function ConnectButtonPushed(app, event)
            % Grab the number from the numeric edit field
            portNum = app.PortConnect.Value;
            try
                % Instantiate the Robot and assign it to the app property
                app.Robot = PlateLoader(portNum);
                app.isConnected = true;
                app.Response.Value = sprintf("Connected to port " + ...
                    "%d", portNum);
            catch
                app.Response.Value = sprintf("ERROR: Could not " + ...
                    "connect to robot on port %d.", portNum);
                app.isConnected = false;
            end
        end

        % Callback function: not associated with a component
        function PortConnectValueChanged(app, event)
            % OOPS: DON'T NEED BUT IDK HOW TO DELETE SECTION (SAME HERE
            % BRO)
        end

        % Callback function: not associated with a component
        function DisconnectButtonPushed(app, event)
            if app.isConnected
                app.Robot = []; % Deletes the object and frees the port
                app.isConnected = false;
                app.Response.Value = "Robot disconnected.";
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ClawGrip = 2;
            app.ClawIndex = 3;
            app.ImageClaw.ImageSource = app.ClawImages(app.ClawGrip);
            app.ImageClaw.Position = app.ClawPos(app.ClawIndex, :);
            app.ExtendBars.Visible = 'off';
            
            command = 'RESET';

            response = app.Sim.sendCommand(command);
            app.Response.Value = response;
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            % OOPS: DON'T NEED BUT IDK HOW TO DELETE SECTION
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % DON'T NEED
            % indices = event.Indices;
            % newData = event.NewData;
            % 
            % % app.updatedUItable.Data = newData;
            % app.UITable.Data(indices(1), indices(2)) = newData;
        end

        % Button pushed function: SetDelaysButton
        function SetDelaysButtonPushed(app, event)
            if app.isConnected
                % 1. Read the current edited data from the UITable
                tableData = app.UITable.Data; 

                % Ensure tableData is numeric (if table contains cell array data, convert it)
                if iscell(tableData)
                    tableData = cell2mat(tableData);
                end

                % 2. Create a 5x5 matrix filled with zeros
                delays5x5 = zeros(5, 5);

                % 3. Map the 5x3 table values into columns 2, 3, and 4[cite: 1]
                delays5x5(:, 2:4) = tableData;

                % 4. Send the updated matrix to the active robot object[cite: 1, 3]
                app.Robot.setTimeValues(delays5x5);

                % 5. Update the GUI status display with the robot's reply[cite: 1]
                app.Response.Value = 'TIME DELAYS UPDATED';
            else
               app.Response.Value = 'PLEASE CONNECT ROBOT TO UPDATE TIME DELAYS';
            end
            

        end

        % Button pushed function: ResetDelaysButton
        function ResetDelaysButtonPushed(app, event)
            app.UITable.Data = app.defaultTimeTable(:, 2:4);

            if app.isConnected
                app.Robot.resetDefaultTimes();
            end

            app.Response.Value = 'TIME DELAYS RESET';

        end

        % Image clicked function: ImageBackground
        function UITableCellEdit2(app, event)
            % OOPS: DON'T NEED BUT IDK HOW TO DELETE SECTION
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PlateLoaderGUIUIFigure = uifigure('Visible', 'off');
            app.PlateLoaderGUIUIFigure.Name = 'Plate Loader GUI';
            app.ImageBackground = uiimage(app.PlateLoaderGUIUIFigure);
            app.ImageBackground.ImageClickedFcn = createCallbackFcn(app, @UITableCellEdit2, true);
            app.ImageBackground.ImageSource = fullfile(pathToMLAPP, 'robot_background.jpg');
            app.ImageClaw = uiimage(app.PlateLoaderGUIUIFigure);
            app.ImageClaw.ImageSource = fullfile(pathToMLAPP, 'gripper_closed_no_plate.jpg');
            app.Button_1 = uibutton(app.PlateLoaderGUIUIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.PlateLoaderGUIUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.PlateLoaderGUIUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.PlateLoaderGUIUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.PlateLoaderGUIUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.ExtendBars = uiimage(app.PlateLoaderGUIUIFigure);
            app.ExtendBars.ImageSource = fullfile(pathToMLAPP, 'extended_bars.jpg');
            app.ResetButton = uibutton(app.PlateLoaderGUIUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GripperPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.GripperPanel.Title = 'Gripper';
            app.CloseButton = uibutton(app.GripperPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.GripperPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ZAxisPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.ZAxisPanel.Title = 'Z-Axis';
            app.ExtendButton = uibutton(app.ZAxisPanel, 'push');
            app.ExtendButton.ButtonPushedFcn = createCallbackFcn(app, @ExtendButtonPushed, true);
            app.ExtendButton.Text = 'Extend';
            app.RetractButton = uibutton(app.ZAxisPanel, 'push');
            app.RetractButton.ButtonPushedFcn = createCallbackFcn(app, @RetractButtonPushed, true);
            app.RetractButton.Text = 'Retract';
            app.ResponsesPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.ResponsesPanel.Title = 'Responses';
            app.GetStatusButton = uibutton(app.ResponsesPanel, 'push');
            app.GetStatusButton.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButtonPushed, true);
            app.GetStatusButton.Text = 'Get Status';
            app.Response = uitextarea(app.ResponsesPanel);
            app.SetTimeDelaysPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.SetTimeDelaysPanel.Title = 'Set Time Delays';
            app.UITable = uitable(app.SetTimeDelaysPanel);
            app.UITable.ColumnName = {'2'; '3'; '4'};
            app.UITable.RowName = {'{''1''; ''2''; ''3''; ''4''; ''5''}'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SetDelaysButton = uibutton(app.SetTimeDelaysPanel, 'push');
            app.SetDelaysButton.ButtonPushedFcn = createCallbackFcn(app, @SetDelaysButtonPushed, true);
            app.SetDelaysButton.Text = 'Set Delays';
            app.ResetDelaysButton = uibutton(app.SetTimeDelaysPanel, 'push');
            app.ResetDelaysButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDelaysButtonPushed, true);
            app.ResetDelaysButton.Text = 'Reset Delays';
            app.MovePlatePanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.MovePlatePanel.Title = 'Move Plate';
            app.MovePlateButton = uibutton(app.MovePlatePanel, 'push');
            app.MovePlateButton.ButtonPushedFcn = createCallbackFcn(app, @MovePlateButtonPushed, true);
            app.MovePlateButton.Text = 'Move Plate';
            app.FromDropDownLabel = uilabel(app.MovePlatePanel);
            app.FromDropDownLabel.Text = 'From:';
            app.FromDropDown = uidropdown(app.MovePlatePanel);
            app.FromDropDown.Items = {'1', '2', '3', '4', '5'};
            app.FromDropDown.Value = '1';
            app.ToLabel = uilabel(app.MovePlatePanel);
            app.ToLabel.Text = 'To:';
            app.ToDropDown = uidropdown(app.MovePlatePanel);
            app.ToDropDown.Items = {'1', '2', '3', '4', '5'};
            app.ToDropDown.Value = '1';
            app.SpecialMovesPanel = uipanel(app.PlateLoaderGUIUIFigure);
            app.SpecialMovesPanel.Title = 'Special Moves';
            app.ChokeSlamButton = uibutton(app.SpecialMovesPanel, 'push');
            app.ChokeSlamButton.ButtonPushedFcn = createCallbackFcn(app, @ChokeSlamButtonPushed, true);
            app.ChokeSlamButton.Text = 'Choke Slam';
            app.ThankYouButton = uibutton(app.SpecialMovesPanel, 'push');
            app.ThankYouButton.ButtonPushedFcn = createCallbackFcn(app, @ThankYouButtonPushed, true);
            app.ThankYouButton.Text = 'Thank You!';
            app.Plate = uiimage(app.PlateLoaderGUIUIFigure);
            app.Plate.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            app.Plate2 = uiimage(app.PlateLoaderGUIUIFigure);
            app.Plate2.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            % Show the figure after all components are created
            app.PlateLoaderGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlateLoaderGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlateLoaderGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlateLoaderGUIUIFigure)
        end
    end
end