classdef MATLAB_Car_MQTT_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab6UIFigure                  matlab.ui.Figure
        ModesPanel                    matlab.ui.container.Panel
        LineFollowingButton           matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        DriveUntilLineButton          matlab.ui.control.Button
        DriveUntilWallButton          matlab.ui.control.Button
        MiscPanel                     matlab.ui.container.Panel
        BeepButton                    matlab.ui.control.Button
        SensorStreamingPanel          matlab.ui.container.Panel
        TimestampLabel                matlab.ui.control.Label
        LeftLamp                      matlab.ui.control.Lamp
        LeftLampLabel                 matlab.ui.control.Label
        RightLamp                     matlab.ui.control.Lamp
        RightLampLabel                matlab.ui.control.Label
        MiddleLamp                    matlab.ui.control.Lamp
        MiddleLampLabel               matlab.ui.control.Label
        StreamingSensorValuesSwitch   matlab.ui.control.Switch
        StreamingSensorValuesSwitchLabel  matlab.ui.control.Label
        IdidntknowwhattoputherePanel  matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        Label                         matlab.ui.control.Label
        SpeedSlider                   matlab.ui.control.Slider
        SpeedSliderLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
        priorKey = "";
    end
    
    methods (Access = private)

        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message)
            messageStruct = jsondecode(message);
            messageType = messageStruct.type

            
            if strcmp(messageType, "set_speeds")
                messagePayload = messageStruct.payload
            end

            if strcmp(messageType, "line_sensor_readings")
                messagePayload = messageStruct.payload
                app.TimestampLabel.Text = messagePayload.timestamp;
                app.LeftLamp.Color = messagePayload.left;
                app.MiddleLamp.Color = messagePayload.middle;
                app.RightLamp.Color = messagePayload.right;
            end

        end


        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;

            if nargin > 2
                messageStruct.payload = messagePayload;
            end

            messageJson = jsonencode(messageStruct);
            write(app.mqClient, "me435/mccreebs/to_pi", messageJson);
            fprintf("Sent: %s\n", messageJson);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/mccreebs/#", "Callback", @app.myCallback)
        end

        % Key press function: Lab6UIFigure
        function Lab6UIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key, app.priorKey)
                return; % The key is being held down. Ignore This
            end
            app.priorKey = key;
            
            speed = round(app.SpeedSlider.Value);

            if strcmp(key, "uparrow")
                app.sendMessage("set_speeds", [speed, speed, speed, speed])
            end

            if strcmp(key, "downarrow")
                app.sendMessage("set_speeds", [-speed, -speed, -speed, -speed])
            end

            if strcmp(key, "leftarrow")
                app.sendMessage("set_speeds", [-speed, speed, speed, -speed])
            end

            if strcmp(key, "rightarrow")
                app.sendMessage("set_speeds", [speed, -speed, -speed, speed])
            end

            if strcmp(key, "a")
                app.sendMessage("set_speeds", [-speed/2, -speed/2, speed/2, speed/2])
            end

            if strcmp(key, "d")
                app.sendMessage("set_speeds", [speed/2, speed/2, -speed/2, -speed/2])
            end

            if strcmp(key, "q")
                app.sendMessage("beep")
            end
        end

        % Key release function: Lab6UIFigure
        function Lab6UIFigureKeyRelease(app, event)
            app.priorKey = "";
            app.sendMessage("stop")

            
        end

        % Button pushed function: BeepButton
        function BeepButtonPushed(app, event)
            app.sendMessage("beep")
        end

        % Button pushed function: DriveUntilWallButton
        function DriveUntilWallButtonPushed(app, event)
            app.sendMessage("mode", "drive_until_wall")
        end

        % Button pushed function: DriveUntilLineButton
        function DriveUntilLineButtonPushed(app, event)
            app.sendMessage("mode", "drive_until_line")
        end

        % Value changed function: StreamingSensorValuesSwitch
        function StreamingSensorValuesSwitchValueChanged(app, event)
            value = app.StreamingSensorValuesSwitch.Value;
            
            if strcmp(value, "On")
                app.sendMessage("is_streaming", true)
            else
                app.sendMessage("is_streaming", false)
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.sendMessage("mode", "none")
        end

        % Button pushed function: LineFollowingButton
        function LineFollowingButtonPushed(app, event)
            app.sendMessage("mode", "line_following")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Lab6UIFigure = uifigure('Visible', 'off');
            app.Lab6UIFigure.Name = 'Lab 6';
            app.Lab6UIFigure.KeyPressFcn = createCallbackFcn(app, @Lab6UIFigureKeyPress, true);
            app.Lab6UIFigure.KeyReleaseFcn = createCallbackFcn(app, @Lab6UIFigureKeyRelease, true);
            app.SpeedSliderLabel = uilabel(app.Lab6UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.Lab6UIFigure);
            app.SpeedSlider.Value = 40;
            app.Label = uilabel(app.Lab6UIFigure);
            app.Label.Text = 'Click in the area below to use the keyboard';
            app.IdidntknowwhattoputherePanel = uipanel(app.Lab6UIFigure);
            app.IdidntknowwhattoputherePanel.Title = 'I didn''t know what to put here';
            app.Image = uiimage(app.IdidntknowwhattoputherePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'silly-propeller-hat.png');
            app.SensorStreamingPanel = uipanel(app.Lab6UIFigure);
            app.SensorStreamingPanel.Title = 'Sensor Streaming';
            app.StreamingSensorValuesSwitchLabel = uilabel(app.SensorStreamingPanel);
            app.StreamingSensorValuesSwitchLabel.Text = 'Streaming Sensor Values';
            app.StreamingSensorValuesSwitch = uiswitch(app.SensorStreamingPanel, 'slider');
            app.StreamingSensorValuesSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamingSensorValuesSwitchValueChanged, true);
            app.MiddleLampLabel = uilabel(app.SensorStreamingPanel);
            app.MiddleLampLabel.Text = 'Middle';
            app.MiddleLamp = uilamp(app.SensorStreamingPanel);
            app.RightLampLabel = uilabel(app.SensorStreamingPanel);
            app.RightLampLabel.Text = 'Right';
            app.RightLamp = uilamp(app.SensorStreamingPanel);
            app.LeftLampLabel = uilabel(app.SensorStreamingPanel);
            app.LeftLampLabel.Text = 'Left';
            app.LeftLamp = uilamp(app.SensorStreamingPanel);
            app.TimestampLabel = uilabel(app.SensorStreamingPanel);
            app.TimestampLabel.Text = 'Timestamp';
            app.MiscPanel = uipanel(app.Lab6UIFigure);
            app.MiscPanel.Title = 'Misc';
            app.BeepButton = uibutton(app.MiscPanel, 'push');
            app.BeepButton.ButtonPushedFcn = createCallbackFcn(app, @BeepButtonPushed, true);
            app.BeepButton.Text = 'Beep';
            app.ModesPanel = uipanel(app.Lab6UIFigure);
            app.ModesPanel.Title = 'Modes';
            app.DriveUntilWallButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilWallButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilWallButtonPushed, true);
            app.DriveUntilWallButton.Text = 'Drive Until Wall';
            app.DriveUntilLineButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilLineButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilLineButtonPushed, true);
            app.DriveUntilLineButton.Text = 'Drive Until Line';
            app.StopButton = uibutton(app.ModesPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LineFollowingButton = uibutton(app.ModesPanel, 'push');
            app.LineFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @LineFollowingButtonPushed, true);
            app.LineFollowingButton.Text = 'Line Following';
            % Show the figure after all components are created
            app.Lab6UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLAB_Car_MQTT_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab6UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab6UIFigure)
        end
    end
end